classdef CartoToGeo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RectangulairesGographiquesLabel  matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        NLabel                      matlab.ui.control.Label
        EEditFieldLabel             matlab.ui.control.Label
        EEditField                  matlab.ui.control.NumericEditField
        CalculerButton              matlab.ui.control.Button
        LongitudeLabel              matlab.ui.control.Label
        LongitudeEditField          matlab.ui.control.NumericEditField
        LongitudeEditField_2        matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        LongitudeEditField_3        matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        LatitudeLabel               matlab.ui.control.Label
        LatitudeEditField           matlab.ui.control.NumericEditField
        LatitudeEditField_2         matlab.ui.control.NumericEditField
        LatitudeEditField_3         matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        ChoisirlazoneDropDownLabel  matlab.ui.control.Label
        ChoisirlazoneDropDown       matlab.ui.control.DropDown
        SuivantButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculerButton
        function CalculerButtonPushed(app, event)
              X=app.EEditField.Value;
              Y=app.YEditField.Value;
              
              a=6378249.20; b=6356515; e2=(a^2-b^2)/b^2;
              
             landa0_deg=-5.4; 
             landa0=deg2rad(landa0_deg);
             
             switch app.ChoisirlazoneDropDown.Value
                 case 'Zone 1'
                    phi0_deg=33.3; phi0=deg2rad(phi0_deg);
                    X0=500000;
                    Y0=300000;
                    K0=0.999625769;
                 case 'Zone 2'
                    phi0_deg=29.7; phi0=deg2rad(phi0_deg);
                    X0=500000;
                    Y0=300000;
                    K0=0.999615596;
                 case 'Zone 3'
                    phi0_deg=26.1; phi0=deg2rad(phi0_deg);
                    X0=1200000;
                    Y0=400000;
                    K0=0.999616304; 
                 case 'Zone 4'
                    phi0_deg=22.5; phi0=deg2rad(phi0_deg);
                    X0=1500000;
                    Y0=400000;
                    K0=0.999616437; 
             end
             
             %Pour éliminer X0 des paramètres 
             Xnv=X-X0;
             Ynv=Y-Y0;
             
              N0=a/sqrt(1-(e2)*((sin(phi0))^2));
             R0=K0*N0*cot(phi0);
             
             landabar=(1/sin(phi0))*atan(Xnv/(R0-Y));
             landa_cv=landabar+landa0;
             landa=rad2deg(landa_cv);
             
            u=log(tan((pi/4)+(phi/2))-(e2/2)*log((1+sqrt(e2)*sin(phi))/(1-(sqrt(e2)*sin(phi)))));
            u0=log(tan((pi/4)+(phi0/2)))+((e2/2)*log((1+sqrt(e2)*sin(phi0))/(1-sqrt(e2)*sin(phi0))));
            ubar=u-u0;
            
             landabar=landa - landa0 ;
            R=R0*exp(-sin(phi0)*ubar);
            
            %On considère une précision n 
            
            n=(((0.0001)/(1.1111))*(0.0001)*(pi/200));
             E0=((e2)/2)*log(((1+sqrt(e2)*sin(phi0))/(1-sqrt(e2)*sin(phi0))));
             phi1=2*atan((exp(u+E0)-1)/(exp(u+E0)+1)); 
             while (abs(phi1-phi0)> n )
             phi0=phi1;    
             E1=((e2)/2)*log(((1+sqrt(e2)*sin(phi0))/(1-sqrt(e2)*sin(phi0))));
             phi1=2*atan((exp(u+E1)-1)/(exp(u+E1)+1));
             end
             
             %phi
             phi=rad2deg(phi1);
             phi_deg= floor(phi);
             phi_min= floor((phi-phi_deg)*60);
             phi_sec= (phi-phi_deg-(phi_min/60))*3600;
             app.LatitudeEditField.Value= phi_deg;
             app.LatitudeEditField_2.Value = phi_min;
             app.LatitudeEditField_3.Value =phi_sec;
             
             landa_deg= fix(landa);
             landa_min= fix(abs(landa-landa_deg)*60);
             landa_sec=fix (abs(landa-landa_deg-(landa_min/60)))*3600;
        
             
             app.LongitudeEditField_3.Value= landa_deg;
             app.LongitudeEditField.Value = landa_min;
             app.LongitudeEditField_2.Value =landa_sec;
             
             
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            finprog
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RectangulairesGographiquesLabel = uilabel(app.UIFigure);
            app.RectangulairesGographiquesLabel.Text = 'Rectangulaires ---> Géographiques';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N=';
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Text = 'E=';
            app.EEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculerButton = uibutton(app.UIFigure, 'push');
            app.CalculerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerButtonPushed, true);
            app.CalculerButton.Text = 'Calculer';
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude λ : ';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '''';
            app.LongitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '"';
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude  ф : ';
            app.LatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '''';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '"';
            app.ChoisirlazoneDropDownLabel = uilabel(app.UIFigure);
            app.ChoisirlazoneDropDownLabel.Text = 'Choisir la zone ';
            app.ChoisirlazoneDropDown = uidropdown(app.UIFigure);
            app.ChoisirlazoneDropDown.Items = {'Zone 1', 'Zone 2', 'Zone 3', 'Zone 4'};
            app.ChoisirlazoneDropDown.Value = 'Zone 1';
            app.SuivantButton = uibutton(app.UIFigure, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CartoToGeo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end