classdef GeoRectan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GographiquesRectangulairesPanel  matlab.ui.container.Panel
        ChoisirlazoneLabel      matlab.ui.control.Label
        DropDown                matlab.ui.control.DropDown
        CoordgographiquesLabel  matlab.ui.control.Label
        LongitudeLabel          matlab.ui.control.Label
        LatitudeLabel           matlab.ui.control.Label
        LatitudeEditField       matlab.ui.control.NumericEditField
        LongitudeEditField      matlab.ui.control.NumericEditField
        LongitudeEditField_2    matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        LatitudeEditField_2     matlab.ui.control.NumericEditField
        LatitudeEditField_3     matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        Label_4                 matlab.ui.control.Label
        LongitudeEditField_3    matlab.ui.control.NumericEditField
        Label_5                 matlab.ui.control.Label
        Label_6                 matlab.ui.control.Label
        CalculerButton          matlab.ui.control.Button
        CoordrectangulairesCartographiquesLabel  matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        NLabel                  matlab.ui.control.Label
        EEditFieldLabel         matlab.ui.control.Label
        EEditField              matlab.ui.control.NumericEditField
        SuivantButton           matlab.ui.control.Button
        XcartoLabel             matlab.ui.control.Label
        YcartoLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculerButton
        function CalculerButtonPushed(app, event)
             
            %D'abord on convertit les unités des coord. géographiques en radians
            phi_deg= app.LatitudeEditField.Value;
            phi_min= app.LatitudeEditField_2.Value;
            phi_sec= app.LatitudeEditField_3.Value;
            
            landa_deg= app.LongitudeEditField_3.Value;
            landa_min= app.LongitudeEditField.Value ;
            landa_sec= app.LongitudeEditField_2.Value;
            
            phi= phi_deg+(phi_min/60)+(phi_sec/3600);
            phi=deg2rad(phi);
            
            landa= landa_deg+(landa_min/60)+(landa_sec/3600);
            landa=deg2rad(landa);
            disp(landa);
            %Données 
            a=6378249.2; b=6356515.0; e2=(a^2-b^2)/a^2;
            
            
            %Boucle selon la zone choisie
            %La valeur de la longitude Landa0 est la meme dans toutes les
            %zones de la projection 
            landa0_deg=-5.4; 
            landa0=landa0_deg*(pi/180);
            disp(landa0);
            
            switch app.DropDown.Value
            case 'Zone 1'
                    phi0_deg=33.3; phi0=deg2rad(phi0_deg);
                    X0=500000;
                    Y0=300000;
                    K0=0.999625769;
                 case 'Zone 2'
                    phi0_deg=29.7; phi0=deg2rad(phi0_deg);
                    X0=500000;
                    Y0=300000;
                    K0=0.999615596;
                 case 'Zone 3'
                    phi0_deg=26.1; phi0=deg2rad(phi0_deg);
                    X0=1200000;
                    Y0=400000;
                    K0=0.999616304; 
                 case 'Zone 4'
                    phi0_deg=22.5; phi0=deg2rad(phi0_deg);
                    X0=1500000;
                    Y0=400000;
                    K0=0.999616437;
            end
            
            
            N0=a/sqrt(1-(e2)*((sin(phi0))^2));
            R0=K0*N0*cot(phi0);
            u=log(tan((pi/4)+(phi/2))-(e2/2)*log((1+sqrt(e2)*sin(phi))/(1-(sqrt(e2)*sin(phi)))));
            u0=log(tan((pi/4)+(phi0/2)))+((e2/2)*log((1+sqrt(e2)*sin(phi0))/(1-sqrt(e2)*sin(phi0))));
            ubar=u-u0;
            landabar=landa - landa0 ;
            R=R0*exp(-sin(phi0)*ubar);
            
            %Xcarto 
            X=R*sin(sin(phi0)*landabar)+X0; 
            
            %Y carto 
            Y=R0-R*cos(sin(phi0)*landabar)+Y0;
            
            %Affichage du résultat 
            app.EEditField.Value= X;
            app.YEditField.Value = Y;
            
            disp(app.EEditField.Value);
            disp(app.YEditField.Value);
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            CartoToGeo
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GographiquesRectangulairesPanel = uipanel(app.UIFigure);
            app.GographiquesRectangulairesPanel.Title = 'Géographiques --> Rectangulaires ';
            app.ChoisirlazoneLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.ChoisirlazoneLabel.Text = 'Choisir la zone :';
            app.DropDown = uidropdown(app.GographiquesRectangulairesPanel);
            app.DropDown.Items = {'Zone 1', 'Zone 2', 'Zone 3', 'Zone 4'};
            app.DropDown.Value = 'Zone 1';
            app.CoordgographiquesLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.CoordgographiquesLabel.Text = 'Coord.géographiques ';
            app.LongitudeLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.LongitudeLabel.Text = 'Longitude λ : ';
            app.LatitudeLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.LatitudeLabel.Text = 'Latitude  ф : ';
            app.LatitudeEditField = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.LongitudeEditField = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.LongitudeEditField_2 = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.Label = uilabel(app.GographiquesRectangulairesPanel);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.GographiquesRectangulairesPanel);
            app.Label_2.Text = '''';
            app.LatitudeEditField_2 = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.LatitudeEditField_3 = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.Label_3 = uilabel(app.GographiquesRectangulairesPanel);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.GographiquesRectangulairesPanel);
            app.Label_4.Text = '''';
            app.LongitudeEditField_3 = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.Label_5 = uilabel(app.GographiquesRectangulairesPanel);
            app.Label_5.Text = '"';
            app.Label_6 = uilabel(app.GographiquesRectangulairesPanel);
            app.Label_6.Text = '"';
            app.CalculerButton = uibutton(app.GographiquesRectangulairesPanel, 'push');
            app.CalculerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerButtonPushed, true);
            app.CalculerButton.Text = 'Calculer';
            app.CoordrectangulairesCartographiquesLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.CoordrectangulairesCartographiquesLabel.Text = 'Coord.rectangulaires (Cartographiques ) ';
            app.YEditField = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.NLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.NLabel.Text = 'N=';
            app.EEditFieldLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.EEditFieldLabel.Text = 'E=';
            app.EEditField = uieditfield(app.GographiquesRectangulairesPanel, 'numeric');
            app.SuivantButton = uibutton(app.GographiquesRectangulairesPanel, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant ';
            app.XcartoLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.XcartoLabel.Text = '(Xcarto)';
            app.YcartoLabel = uilabel(app.GographiquesRectangulairesPanel);
            app.YcartoLabel.Text = '(Ycarto)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeoRectan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end