classdef geotocartez < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GodsiquesCartsiennesLabel  matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        LongitudeLabel             matlab.ui.control.Label
        LongitudeEditField         matlab.ui.control.NumericEditField
        LongitudeEditField_2       matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        LongitudeEditField_3       matlab.ui.control.NumericEditField
        LatitudeLabel              matlab.ui.control.Label
        LatitudeEditField          matlab.ui.control.NumericEditField
        LatitudeEditField_2        matlab.ui.control.NumericEditField
        LatitudeEditField_3        matlab.ui.control.NumericEditField
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        ElvationhEditFieldLabel    matlab.ui.control.Label
        ElvationhEditField         matlab.ui.control.NumericEditField
        mLabel                     matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditField                 matlab.ui.control.NumericEditField
        YLabel                     matlab.ui.control.Label
        ZLabel                     matlab.ui.control.Label
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        ConvertirButton            matlab.ui.control.Button
        PrcdentButton              matlab.ui.control.Button
        SuivantButton              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertirButton
        function ConvertirButtonPushed(app, event)
        
        %D'abord on convertit les unités des coord. géographiques en radians
            phi_deg= app.LatitudeEditField.Value;
            phi_min= app.LatitudeEditField_2.Value;
            phi_sec= app.LatitudeEditField_3.Value;
            
            landa_deg= app.LongitudeEditField_3.Value;
            landa_min= app.LongitudeEditField.Value ;
            landa_sec= app.LongitudeEditField_2.Value;
            
            h=app.ElvationhEditField.Value;
            
            phi= phi_deg+(phi_min/60)+(phi_sec/3600);
            phi=deg2rad(phi);
            
            landa= landa_deg+(landa_min/60)+(landa_sec/3600);
            landa=deg2rad(landa);
            
            %on donne :
            a=6378249.2; b=6356515.0; e2=(a^2-b^2)/a^2; 
            w=sqrt(1-e2*(sin(phi))^2);
            N=a/w; %Rayon de courbure du 1e vertical (rayon minimal)
            
            X=(N+h)*cos(phi)*cos(landa);
            Y=(N+h)*cos(phi)*sin(landa);
            Z=(N*(1-e2)+h)*sin(phi);
            
            app.XEditField.Value=X;
            app.YEditField.Value=Y;
            app.ZEditField.Value=Z;
            
      %Affichage      
            disp(app.XEditField.Value);
            disp(app.YEditField.Value);
            disp(app.ZEditField.Value);

        end

        % Button pushed function: PrcdentButton
        function PrcdentButtonPushed(app, event)
            passageit
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            passageit
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GodsiquesCartsiennesLabel = uilabel(app.UIFigure);
            app.GodsiquesCartsiennesLabel.Text = 'Géodésiques ---> Cartésiennes ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Geodesiques.JPG';
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude λ : ';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '''''';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '''';
            app.LongitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude  ф : ';
            app.LatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '''''';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '''';
            app.ElvationhEditFieldLabel = uilabel(app.UIFigure);
            app.ElvationhEditFieldLabel.Text = 'Elévation h :';
            app.ElvationhEditField = uieditfield(app.UIFigure, 'numeric');
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y =';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z=';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X = ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvertirButton = uibutton(app.UIFigure, 'push');
            app.ConvertirButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButtonPushed, true);
            app.ConvertirButton.Text = 'Convertir ';
            app.PrcdentButton = uibutton(app.UIFigure, 'push');
            app.PrcdentButton.ButtonPushedFcn = createCallbackFcn(app, @PrcdentButtonPushed, true);
            app.PrcdentButton.Text = 'Précédent';
            app.SuivantButton = uibutton(app.UIFigure, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = geotocartez

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end