classdef passagedir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PassageDirectButton      matlab.ui.control.Button
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YLabel                   matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        ZLabel                   matlab.ui.control.Label
        ZEditField               matlab.ui.control.NumericEditField
        LongitudeLabel           matlab.ui.control.Label
        LongitudeEditField       matlab.ui.control.NumericEditField
        LatitudeLabel            matlab.ui.control.Label
        LatitudeEditField        matlab.ui.control.NumericEditField
        ElvationhEditFieldLabel  matlab.ui.control.Label
        ElvationhEditField       matlab.ui.control.NumericEditField
        ConvertirButton          matlab.ui.control.Button
        LongitudeEditField_2     matlab.ui.control.NumericEditField
        LongitudeEditField_3     matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Label_4                  matlab.ui.control.Label
        LatitudeEditField_2      matlab.ui.control.NumericEditField
        LatitudeEditField_3      matlab.ui.control.NumericEditField
        Label_5                  matlab.ui.control.Label
        Label_6                  matlab.ui.control.Label
        Label_7                  matlab.ui.control.Label
        mLabel                   matlab.ui.control.Label
        SuivantButton            matlab.ui.control.Button
        PrcdentButton            matlab.ui.control.Button
    end

    
    methods (Access = private)
              
         %On va utiliser les données de l'ellipsoide de clarke 1880
         %Données
         
         function cartesiennesdirect(app)
         
         X=app.XEditField.Value;
         Y=app.YEditField.Value;
         Z=app.ZEditField.Value;
    
          a=6378249.2;
          f=1/293.4663;
          b=a*(1-f);
          e2=(2*f)-(f^2);
          rad=180/pi;

         r=sqrt((X^2)+(Y^2)+(Z^2));
         u=(atan((Z/sqrt(X^2+Y^2))*((1-f)+(a*e2)/r)))*rad;
         %longitude 
         landa=(atan(Y/X))*rad;
          %latitude géodésique
          phi =atan((Z*(1-f)+e2*a*(sin(u/rad)^3))/((1-f)*sqrt(X^2+Y^2)-e2*a*(cos(u/rad)^3)))*rad; 
         %élévation ellipsoidale
          h=(sqrt(X^2+Y^2)*cos(phi/rad))+(Z*sin(phi/rad))-(a*sqrt(1-e2*(sin(phi/rad)^2)));

          landa= abs(landa);

          LANDA1= floor(landa);
          LANDA2= floor((landa-LANDA1)*60);
          LANDA3=(landa-LANDA1-(LANDA2/60))*3600; 
        
 
            phi=abs(phi);

          PHI1=floor(phi);
          PHI2= floor((phi-PHI1)*60);
          PHI3= floor(phi-PHI1-(PHI2/60))*3600; 
         
          %Affichage 
           app.LongitudeEditField.Value=PHI1 ;
           app.LongitudeEditField_2.Value= PHI2 ; 
           app.LongitudeEditField_3.Value= PHI3; 
         
          
           app.LatitudeEditField.Value= LANDA1;
           app.LatitudeEditField_2.Value=LANDA2 ; 
           app.LatitudeEditField_3.Value= LANDA3;  
           
           app.ElvationhEditField.Value= h;
           
           disp(app.LongitudeEditField.Value);
           disp(app.LongitudeEditField_2.Value);
           disp( app.LongitudeEditField_3.Value);
           disp(app.LatitudeEditField.Value);
           disp( app.LatitudeEditField_2.Value);
           disp(app.LatitudeEditField_3.Value);
           disp(app.ElvationhEditField.Value);
           
         end 
         
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertirButton
        function ConvertirButtonPushed(app, event)
                 cartesiennesdirect(app)
        end

        % Button pushed function: PrcdentButton
        function PrcdentButtonPushed(app, event)
            MenuPassage
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            geotocartez
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PassageDirectButton = uibutton(app.UIFigure, 'push');
            app.PassageDirectButton.Text = 'Passage Direct';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X = ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y =';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z =';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude λ : ';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude  ф : ';
            app.LatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElvationhEditFieldLabel = uilabel(app.UIFigure);
            app.ElvationhEditFieldLabel.Text = 'Elévation h :';
            app.ElvationhEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvertirButton = uibutton(app.UIFigure, 'push');
            app.ConvertirButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButtonPushed, true);
            app.ConvertirButton.Text = 'Convertir ';
            app.LongitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '''''';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '''';
            app.LatitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '''''';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '''';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.SuivantButton = uibutton(app.UIFigure, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant ';
            app.PrcdentButton = uibutton(app.UIFigure, 'push');
            app.PrcdentButton.ButtonPushedFcn = createCallbackFcn(app, @PrcdentButtonPushed, true);
            app.PrcdentButton.Text = 'Précédent';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = passagedir

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end