classdef passageit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PassageItratifButton            matlab.ui.control.Button
        YEditField                      matlab.ui.control.NumericEditField
        ZEditField                      matlab.ui.control.NumericEditField
        LongitudeLabel                  matlab.ui.control.Label
        LatitudeLabel                   matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        ElvationhEditFieldLabel         matlab.ui.control.Label
        ElvationhEditField              matlab.ui.control.NumericEditField
        ConvertirButton                 matlab.ui.control.Button
        LongitudeEditField              matlab.ui.control.NumericEditField
        LongitudeEditField_2            matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        LatitudeEditField_2             matlab.ui.control.NumericEditField
        LatitudeEditField_3             matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        ZLabel                          matlab.ui.control.Label
        Onprenduneprcisiongale001Label  matlab.ui.control.Label
        LongitudeEditField_3            matlab.ui.control.NumericEditField
        Label_7                         matlab.ui.control.Label
        PrcdentButton                   matlab.ui.control.Button
        SuivantButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertirButton
        function ConvertirButtonPushed(app, event)
            X= app.XEditField.Value;
            Y= app.YEditField.Value;
            Z= app.ZEditField.Value;
            precision=0.01;a=6378249.2; f=1/293.4663; e2=(2*f)-(f^2); rad=180/pi;
            
            %longitude 
LANDA=(atan(Y/X))*rad;
p=sqrt((X^2)+(Y^2));

PHI(1,1)=atan(Z/(p*(1-e2)))*rad;

  N(1,1)=a/sqrt(1-(e2*(sin(PHI(1,1)/rad))));
  h(1,1)=(p/cos(PHI(1,1)/rad))-N(1,1);
  
  n=2;
  val=10;
  
  while precision < val 
      dim=N(1,n-1)/(N(1,n-1)+h(1,n-1));
      PHI(1,n)= atan(Z/p*(1-e2*dim))*rad;
       N(1,n)=a/sqrt(1-(e2*(sin(PHI(1,n)/rad)^2)));
        h(1,n)=(p/cos(PHI(1,n)/rad))-N(1,n);
        
        PHI_finale=PHI(1,n);
        val= abs(((PHI(1,n)-PHI(1,n-1))/PHI(1,n)));
        
        if(val< precision)
            PHI_finale= abs(PHI_finale);
            PHI1= floor(PHI_finale);
            PHI2=floor((PHI_finale - PHI1)*60);
            PHI3=(PHI_finale-PHI1-(PHI2/60))*3600;
            break;
        else
            n=n+1;
        end
  end
  
  LANDA= abs(LANDA);
  LANDA1= floor(LANDA);
  LANDA2=floor((LANDA-LANDA1)*60);
  LANDA3=(LANDA-LANDA1-(LANDA2/60))*3600;
  
  app.LongitudeEditField_3.Value=LANDA1;
  app.LongitudeEditField.Value=LANDA2;  
  app.LongitudeEditField_2.Value=LANDA3;  
  app.LatitudeEditField.Value=PHI1;
  app.LatitudeEditField_2.Value=PHI2;
  app.LatitudeEditField_3.Value=PHI3;
  
 %Affichage      
            disp(app.LongitudeEditField_3.Value);
            disp(app.LongitudeEditField.Value);
            disp(app.LongitudeEditField_2.Value);
            disp(app.LatitudeEditField.Value);
            disp(app.LatitudeEditField_2.Value);
            disp(app.LatitudeEditField_3.Value);

        end

        % Button pushed function: PrcdentButton
        function PrcdentButtonPushed(app, event)
            passagedir
        end

        % Button pushed function: SuivantButton
        function SuivantButtonPushed(app, event)
            geotocartez
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PassageItratifButton = uibutton(app.UIFigure, 'push');
            app.PassageItratifButton.Text = 'Passage Itératif';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude λ : ';
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude  ф : ';
            app.LatitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElvationhEditFieldLabel = uilabel(app.UIFigure);
            app.ElvationhEditFieldLabel.Text = 'Elévation h :';
            app.ElvationhEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConvertirButton = uibutton(app.UIFigure, 'push');
            app.ConvertirButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButtonPushed, true);
            app.ConvertirButton.Text = 'Convertir ';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LongitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '''''';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '''';
            app.LatitudeEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.LatitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '''''';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '''';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X = ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y =';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z=';
            app.Onprenduneprcisiongale001Label = uilabel(app.UIFigure);
            app.Onprenduneprcisiongale001Label.Text = 'On prend une précision égale à 0.01';
            app.LongitudeEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Il n''est pas conseillé de tester cette fonction, ça risque de ralentir la performance de votre machine .';
            app.PrcdentButton = uibutton(app.UIFigure, 'push');
            app.PrcdentButton.ButtonPushedFcn = createCallbackFcn(app, @PrcdentButtonPushed, true);
            app.PrcdentButton.Text = 'Précédent';
            app.SuivantButton = uibutton(app.UIFigure, 'push');
            app.SuivantButton.ButtonPushedFcn = createCallbackFcn(app, @SuivantButtonPushed, true);
            app.SuivantButton.Text = 'Suivant ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = passageit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end